classdef power_flow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UITable                       matlab.ui.control.Table
        PowerFlowCalculatorLabel      matlab.ui.control.Label
        UITable_2                     matlab.ui.control.Table
        CalculateForToleranceButton   matlab.ui.control.StateButton
        UITable_3                     matlab.ui.control.Table
        Label                         matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        EditField_2                   matlab.ui.control.NumericEditField
        EditField_3                   matlab.ui.control.NumericEditField
        EditField_4                   matlab.ui.control.NumericEditField
        EditField_5                   matlab.ui.control.NumericEditField
        EditField_21Label             matlab.ui.control.Label
        EditField_41Label             matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        EditField_62Label             matlab.ui.control.Label
        EditField_62                  matlab.ui.control.NumericEditField
        EditField_63                  matlab.ui.control.NumericEditField
        EditField_64                  matlab.ui.control.NumericEditField
        EditField_65                  matlab.ui.control.NumericEditField
        EditField_66                  matlab.ui.control.NumericEditField
        EditField_67Label             matlab.ui.control.Label
        EditField_67                  matlab.ui.control.NumericEditField
        EditField_68                  matlab.ui.control.NumericEditField
        EditField_69                  matlab.ui.control.NumericEditField
        EditField_70                  matlab.ui.control.NumericEditField
        EditField_71                  matlab.ui.control.NumericEditField
        EditField_72Label             matlab.ui.control.Label
        EditField_72                  matlab.ui.control.NumericEditField
        EditField_73                  matlab.ui.control.NumericEditField
        EditField_74                  matlab.ui.control.NumericEditField
        EditField_75                  matlab.ui.control.NumericEditField
        EditField_76                  matlab.ui.control.NumericEditField
        EditField_77Label             matlab.ui.control.Label
        EditField_77                  matlab.ui.control.NumericEditField
        EditField_78                  matlab.ui.control.NumericEditField
        EditField_79                  matlab.ui.control.NumericEditField
        EditField_80                  matlab.ui.control.NumericEditField
        EditField_81                  matlab.ui.control.NumericEditField
        EditField_82Label             matlab.ui.control.Label
        EditField_82                  matlab.ui.control.NumericEditField
        EditField_83                  matlab.ui.control.NumericEditField
        EditField_84                  matlab.ui.control.NumericEditField
        EditField_85                  matlab.ui.control.NumericEditField
        EditField_86                  matlab.ui.control.NumericEditField
        EditField_87Label             matlab.ui.control.Label
        EditField_87                  matlab.ui.control.NumericEditField
        EditField_88                  matlab.ui.control.NumericEditField
        EditField_89                  matlab.ui.control.NumericEditField
        EditField_90                  matlab.ui.control.NumericEditField
        EditField_91                  matlab.ui.control.NumericEditField
        EditField_92Label             matlab.ui.control.Label
        EditField_92                  matlab.ui.control.NumericEditField
        EditField_93                  matlab.ui.control.NumericEditField
        EditField_94                  matlab.ui.control.NumericEditField
        EditField_95                  matlab.ui.control.NumericEditField
        EditField_96                  matlab.ui.control.NumericEditField
        EditField_97Label             matlab.ui.control.Label
        EditField_97                  matlab.ui.control.NumericEditField
        EditField_98                  matlab.ui.control.NumericEditField
        EditField_99                  matlab.ui.control.NumericEditField
        EditField_100                 matlab.ui.control.NumericEditField
        EditField_101                 matlab.ui.control.NumericEditField
        EditField_102Label            matlab.ui.control.Label
        EditField_102                 matlab.ui.control.NumericEditField
        EditField_103                 matlab.ui.control.NumericEditField
        EditField_104                 matlab.ui.control.NumericEditField
        EditField_105                 matlab.ui.control.NumericEditField
        EditField_106                 matlab.ui.control.NumericEditField
        EditField_107Label            matlab.ui.control.Label
        EditField_107                 matlab.ui.control.NumericEditField
        EditField_108                 matlab.ui.control.NumericEditField
        EditField_109                 matlab.ui.control.NumericEditField
        EditField_110                 matlab.ui.control.NumericEditField
        EditField_111                 matlab.ui.control.NumericEditField
        EditField_112Label            matlab.ui.control.Label
        EditField_112                 matlab.ui.control.NumericEditField
        EditField_113                 matlab.ui.control.NumericEditField
        EditField_114                 matlab.ui.control.NumericEditField
        EditField_115                 matlab.ui.control.NumericEditField
        EditField_116                 matlab.ui.control.NumericEditField
        EditField_117Label            matlab.ui.control.Label
        EditField_117                 matlab.ui.control.NumericEditField
        EditField_118                 matlab.ui.control.NumericEditField
        EditField_119                 matlab.ui.control.NumericEditField
        EditField_120                 matlab.ui.control.NumericEditField
        EditField_121                 matlab.ui.control.NumericEditField
        EditField_122Label            matlab.ui.control.Label
        EditField_122                 matlab.ui.control.NumericEditField
        EditField_123                 matlab.ui.control.NumericEditField
        EditField_124                 matlab.ui.control.NumericEditField
        EditField_125                 matlab.ui.control.NumericEditField
        EditField_126                 matlab.ui.control.NumericEditField
        EditField_127Label            matlab.ui.control.Label
        EditField_127                 matlab.ui.control.NumericEditField
        EditField_128                 matlab.ui.control.NumericEditField
        EditField_129                 matlab.ui.control.NumericEditField
        EditField_130                 matlab.ui.control.NumericEditField
        EditField_131                 matlab.ui.control.NumericEditField
        DropDown_2                    matlab.ui.control.DropDown
        DropDown_3                    matlab.ui.control.DropDown
        DropDown_4                    matlab.ui.control.DropDown
        DropDown_5                    matlab.ui.control.DropDown
        NoOfIterationsEditFieldLabel  matlab.ui.control.Label
        NoOfIterationsEditField       matlab.ui.control.NumericEditField
        CorrectionFactorEditField_2   matlab.ui.control.NumericEditField
        NoOfBussSpinnerLabel          matlab.ui.control.Label
        NoOfBussSpinner               matlab.ui.control.Spinner
        UITable2                      matlab.ui.control.Table
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
        end

        % Value changed function: CalculateForToleranceButton
        function CalculateForToleranceButtonValueChanged(app, event)
            value = app.CalculateForToleranceButton.Value;
            
            tnb = app.NoOfBussSpinner.Value;
            
            sb1= app.EditField.Value;
            sb2= app.EditField_2.Value;
            sb3= app.EditField_3.Value;
            sb4= app.EditField_4.Value;
            sb5= app.EditField_5.Value;
            
            eb1 = app.EditField_62.Value;
            eb2 = app.EditField_63.Value;
            eb3 = app.EditField_64.Value;
            eb4 = app.EditField_65.Value;
            eb5 = app.EditField_66.Value;
            
            r1 = app.EditField_67.Value;
            r2 = app.EditField_68.Value;
            r3 = app.EditField_69.Value;
            r4 = app.EditField_70.Value;
            r5 = app.EditField_71.Value;
            
            x1 = app.EditField_72.Value;
            x2 = app.EditField_73.Value;
            x3 = app.EditField_74.Value;
            x4 = app.EditField_75.Value;
            x5 = app.EditField_76.Value;
            
            g1 = app.EditField_77.Value;
            g2 = app.EditField_78.Value;
            g3 = app.EditField_79.Value;
            g4 = app.EditField_80.Value;
            g5 = app.EditField_81.Value;
            
            b1 = app.EditField_82.Value;
            b2 = app.EditField_83.Value;
            b3 = app.EditField_84.Value;
            b4 = app.EditField_85.Value;
            b5 = app.EditField_86.Value;
            
            tc1 = app.EditField_87.Value;
            tc2 = app.EditField_88.Value;
            tc3 = app.EditField_89.Value;
            tc4 = app.EditField_90.Value;
            tc5 = app.EditField_91.Value;
            
            y1 = app.EditField_92.Value;
            y2 = app.EditField_93.Value;
            y3 = app.EditField_94.Value;
            y4 = app.EditField_95.Value;
            y5 = app.EditField_96.Value;
            
            bn1 = app.EditField_102.Value;
            bn2 = app.EditField_103.Value;
            bn3 = app.EditField_104.Value;
            bn4 = app.EditField_105.Value;
            bn5 = app.EditField_106.Value;
            
            pg1 = app.EditField_97.Value;
            pg2 = app.EditField_98.Value;
            pg3 = app.EditField_99.Value;
            pg4 = app.EditField_100.Value;
            pg5 = app.EditField_101.Value;
            
            qg1 = app.EditField_107.Value;
            qg2 = app.EditField_108.Value;
            qg3 = app.EditField_109.Value;
            qg4 = app.EditField_110.Value;
            qg5 = app.EditField_111.Value;
            
            pl1 = app.EditField_112.Value;
            pl2 = app.EditField_113.Value;
            pl3 = app.EditField_114.Value;
            pl4 = app.EditField_115.Value;
            pl5 = app.EditField_116.Value;
            
            ql1 = app.EditField_117.Value;
            ql2 = app.EditField_118.Value;
            ql3 = app.EditField_119.Value;
            ql4 = app.EditField_120.Value;
            ql5 = app.EditField_121.Value;
            
            v1 = app.EditField_122.Value;
            v2 = app.EditField_123.Value;
            v3 = app.EditField_124.Value;
            v4 = app.EditField_125.Value;
            v5 = app.EditField_126.Value;


            ang1 = app.EditField_127.Value;
            ang2 = app.EditField_128.Value;
            ang3 = app.EditField_129.Value;
            ang4 = app.EditField_130.Value;
            ang5 = app.EditField_131.Value;
            
            bt1f = app.DropDown.Value;
            bt2f = app.DropDown_2.Value;
            bt3f = app.DropDown_3.Value;
            bt4f = app.DropDown_4.Value;
            bt5f = app.DropDown_5.Value;
            
            if(strcmp( bt1f, 'Slack'))
                bt1 = 1;
            elseif(strcmp( bt1f, 'PQ'))
                bt1 = 2;
            else
                bt1 = 3;
            end
            
            if(strcmp( bt2f, 'Slack'))
                bt2 = 1;
            elseif(strcmp( bt2f, 'PQ'))
                bt2 = 2;
            else
                bt2 = 3;
            end
            
            if(strcmp( bt3f, 'Slack'))
                bt3 = 1;
            elseif(strcmp( bt3f, 'PQ'))
                bt3 = 2;
            else
                bt3 = 3;
            end
            
            if(strcmp( bt4f, 'Slack'))
                bt4 = 1;
            elseif(strcmp( bt4f, 'PQ'))
                bt4 = 2;
            else
                bt4 = 3;
            end
            
            if(strcmp( bt5f, 'Slack'))
                bt5 = 1;
            elseif(strcmp( bt5f, 'PQ'))
                bt5 = 2;
            else
                bt5 = 3;
            end
            
           
disp (' TABLE 9.2 PAGE # 337   LINE DATA FOR EXAMPLE 9.2 ')
linedata=[sb1     eb1       r1,    x1,   g1,     b1,     tc1,  y1;
          sb2     eb2       r2,    x2,   g2,     b2,     tc2,   y2;
          sb3     eb3       r3,    x3,   g3,     b3,     tc3,   y3;
          sb4     eb4       r4,    x4,   g4,     b4,     tc4,  y4]
      
      
disp (' TABLE 9.3 PAGE # 338   BUS DATA FOR EXAMPLE 9.2 ')    
busdata=[bn1   pg1,     qg1,  pl1,    ql1,  v1,   ang1   bt1;
         bn2   pg2,     qg2,  pl2,    ql2,  v2,   ang2   bt2;
         bn3   pg3,     qg3,  pl3,    ql3,  v3,   ang3   bt3;
         bn4   pg4,     qg4 , pl4,    ql4,  v4,   ang4   bt4]
     %  Last column shows Bus Type: 1.Slack Bus    2.PQ Bus    3.PV Bus
     
     
%=================================================== PROGRAM STARTS HERE ===================================================%     
ss=1i*linedata(:,8);     % Y/2
y=linedata(:,5)+1i*linedata(:,6);
     
totalbuses = tnb;    % total buses
totalbranches = length(linedata(:,1));                      % no. of branches
ybus = zeros(totalbuses,totalbuses);  
w=0;
u=0;
for n=1:totalbuses       
    if busdata(n,8)==3
        w=w+1;  % no of PV busses
    elseif busdata(n,8) == 2
        u=u+1;  % no of PQ busses
    end
end

% ------ INITIALLIZING YBUS ------
for b=1:totalbranches
    ybus((linedata(b,1)),(linedata(b,2)))=-y(b);
    ybus((linedata(b,2)),(linedata(b,1))) =ybus((linedata(b,1)),(linedata(b,2)));
    
end
 for c=1:totalbuses
     for d=1:totalbranches
         if linedata(d,1) == c || linedata(d,2) == c
             ybus(c,c) = ybus(c,c) + y(d) + ss(d);
         end
     end
 end
disp('BUS ADMITTANCE MATRIX ')
ybus
ybusR=zeros(totalbuses,totalbuses);
ybusA=zeros(totalbuses,totalbuses);
z=zeros(totalbuses,8);
busnumber=busdata(:,1);
PG=busdata(:,2);
QG=busdata(:,3);
PL=busdata(:,4);
QL=busdata(:,5);
V=busdata(:,6);
VV=V;
ANG=busdata(:,7);
type = busdata(:,8);
s=(2*totalbuses)-w-2;
J=zeros(s,s);         % initiallizing empty jacobian matrix
MM=zeros(s,1);
AN=ANG;
P = (PG-PL)./100;    % per unit active power at buses
Q = (QG-QL)./100;    % per unit reactive power at buses
tol=1;
iter=0;
kk= app.CorrectionFactorEditField_2.Value;
%------------------------------Rect to Polar--------------------------------
for b=1:totalbranches
    % Real part of ybus
    ybusR((linedata(b,1)),(linedata(b,2)))=abs(-y(b));
    ybusR((linedata(b,2)),(linedata(b,1))) =ybusR((linedata(b,1)),(linedata(b,2)));
    ybusR(b,b)=abs(-ybus(b,b));
    % Angle of ybus    
    ybusA((linedata(b,1)),(linedata(b,2)))=angle(-y(b));
    ybusA((linedata(b,2)),(linedata(b,1))) =ybusA((linedata(b,1)),(linedata(b,2)));
    ybusA(b,b)=angle(-ybus(b,b));
end
ybusR;
ybusA;

while tol > kk


%------------------------ Delta P --------------------------------%
    
for i = 2:totalbuses % neglecting Slack Bus
   
    YVV = 0;
    
    for n = 1:totalbuses
        if i~=n
            YVV = YVV + (ybusR(i,n)* V(n)*V(i))*cos((ybusA(i,n))+busdata(n,7)-busdata(i,7));  % Format Summition of The Equations by multiplying admittance & voltage
        end
        YVV;
    end
             
    Pc(i) = ((abs(V(i))^2)*real(ybus(i,i)))+YVV; % Compute Calculated P.
    deltaP(i)=P(i)-Pc(i); % mismatch of P 
end
Pc; 
dp=deltaP';
for i=2:totalbuses
    MM(i-1,1)=dp(i); % put array of deltaP in first elements in mismatch matrix
end
dpn = numel(dp) - 1 ;% get numbers of deltaP in mismatch matrix
 %------------------------ Delta Q -----------------------------%
  
for i = 2:totalbuses-w  % neglecting Slack Bus and PV buses
   if busdata(i,8)== 2 % if bus is PQ compute 
        YVV = 0;
        for n = 1:totalbuses
            if i~=n
                YVV = YVV + (ybusR(i,n)* V(n)*V(i))*sin((ybusA(i,n))+busdata(n,7)-busdata(i,7));  % Format Summition of The Equations by multiplying admittance & voltage
            end
            YVV;
        end
             
        Qc(i) = -((abs(V(i))^2)*imag(ybus(i,i)))-YVV; % Compute Calculated Q.
        deltaQ(i)=Q(i)-Qc(i);
    end
end
Qc;
dq=deltaQ';
    
for i=2:totalbuses
    if busdata(i,8)== 2
        MM(dpn+1,1)=dq(i); % put array of deltaQ of PQ buses in the last elements in mismatch matrix
        dpn = dpn +1 ;
    end
end 
 
J11=zeros(totalbuses-1,totalbuses-1); % subtract Slack Bus from both columns and rows
J12=zeros(totalbuses-1,u); % subtract Slack Bus in rows , columns will be no of PQ only
J21=zeros(u,totalbuses-1); % Rows will be no of PQ only , subtract Slack Bus from columns
J22=zeros(u,u); % Rows and columns will be no of PQ only

     %----------------------------J11-------------------------------%
    for i=2:totalbuses
        for j=2:totalbuses
            if i~=j
        J11(i-1,j-1)= -abs(((ybusR(i,j)* V(i)*V(j)))*sin((ybusA(i,j))+busdata(j,7)-busdata(i,7)));
            end
               if i==j
                   for n=1:totalbuses
                    if n~=i
                 J11(i-1,i-1)=J11(i-1,i-1)+( abs(((ybusR(i,n)* V(i)*V(n)))*sin((ybusA(i,n))+busdata(n,7)-busdata(i,7))));
                    end
                end
               end
            end
    end
    J11;
    %----------------------------J12-------------------------------%   
    
    for i=2:totalbuses
       
        
        for j=2:totalbuses-w
             if busdata(j,8)== 2
            
            if i~=j
        J12(i-1,j-1)= (abs(V(j))*abs((ybusR(i,j)* V(i))))*cos((ybusA(i,j))+busdata(j,7)-busdata(i,7));
            end
            yv12=0;
            if i==j
                for n=1:totalbuses
                    if n~=i
         yv12=yv12 + abs((ybusR(i,n)* V(n)))*cos((ybusA(i,n))+busdata(n,7)-busdata(i,7));
        
                    end
                end
             J12(i-1,j-1) = abs(V(i))*(2*abs(V(i))*real(ybus(i,i))+yv12);
            end
        end
    end
    end
    J12;
   %----------------------------J21-------------------------------%    
  
   for i=2:totalbuses-w
        if busdata(i,8)== 2
       
       for j=2:totalbuses
           if i~=j
               J21(i-1,j-1)= -abs(((ybusR(i,j)* V(i)*V(j)))*cos((ybusA(i,j))+busdata(j,7)-busdata(i,7)));
            end
               if i==j
                   for n=1:totalbuses
                    if n~=i
                 J21(i-1,j-1)=J21(i-1,j-1)+( abs(((ybusR(i,n)* V(i)*V(n)))*cos((ybusA(i,n))+busdata(n,7)-busdata(i,7))));
                    end
                end
               end
            end
   end
   end
   J21;
    %----------------------------J22-------------------------------%    
    
    for i=2:totalbuses-w
       
        
        for j=2:totalbuses-w
             if busdata(i,8)== 2 && busdata(j,8)== 2
            
            if i~=j
        J22(i-1,j-1)= -(abs(V(j))*abs((ybusR(i,j)* V(i))))*sin((ybusA(i,j))+busdata(j,7)-busdata(i,7));
            end
            yv12=0;
            if i==j
               J22(i-1,j-1)=-(J11(i-1,i-1)) - (2*(abs(V(i))^2)*imag(ybus(i,i)));
            end
        end
    end
    end
    J22;
    %---------------------------------------------------------------------------------%
    
    for k=1:totalbuses-1
        for l=1:totalbuses-1
            
            J(k,l)=J11(k,l);
        end
    end
    
    for k=1:u
        for l=1:totalbuses-1
            
            J(l,k+totalbuses-1)=J12(l,k);
        end
    end
    
    for k=1:u
        for l=1:totalbuses-1
            
            J(k+totalbuses-1,l)=J21(k,l);
        end
    end
    
    for k=1:u
        for l=1:u
            
            J(l+totalbuses-1,k+totalbuses-1)=J22(l,k);
        end
    end
    MM;
    J;
    
    k=inv(J)*MM;
    
      for n=1:totalbuses-1
        S(n)=(ANG(n+1)+k(n));
        sa=radtodeg(S);
        %-------------
        busdata(n+1,7)=S(n);
        
        %-------------
      end
    ANG=busdata(:,7);
    sa;
   
       for m=2:u+1
        V(m)=abs(busdata(m,6)+k(totalbuses-2+m));
        %abs(VV)
      busdata(m,6)=V(m);
       end
     V;
     
   tol=max(max(abs(abs(V) - abs(VV))) ,  max(abs(abs(ANG) - abs(AN))));
  
    iter=iter+1;
  VV=V;
  AN=ANG;
  
  
end
J
app.NoOfIterationsEditField.Value = iter;
%-------------------------DISPLAYING OUTPUTS---------------------------%

for i= 1:totalbuses
    Vr(i) = V(i).*cos(ANG(i))+1i.*V(i).*sin(ANG(i));
end
Vr;
VA=zeros(totalbuses,totalbuses);
VAreverse=zeros(totalbuses,totalbuses);
for i=1:totalbranches
        
    VA(linedata(i,1),linedata(i,2))= Vr(linedata(i,1)).*conj((Vr(linedata(i,1))-Vr(linedata(i,2))).*-ybus(linedata(i,1),linedata(i,2))+Vr(linedata(i,1)).*ss(linedata(i,1)));
    VAreverse(linedata(i,2),linedata(i,1))= Vr(linedata(i,2))*conj((Vr(linedata(i,2))-Vr(linedata(i,1)))*-ybus(linedata(i,2),linedata(i,1))+Vr(linedata(i,2))*ss(linedata(i,2)));

    PF(linedata(i,1),linedata(i,2))=real(VA(linedata(i,1),linedata(i,2)));
    QF(linedata(i,1),linedata(i,2))=imag(VA(linedata(i,1),linedata(i,2)));
    Ploss(i)=VA(linedata(i,1),linedata(i,2))+VAreverse(linedata(i,2),linedata(i,1));
    
end
VA;
sum =0;
for i=1:totalbuses
    sum=sum+ybus(1,i)*Vr(i);
end
VAs=Vr(1)*conj(sum);
Ps =100* real(VAs);
Qs =100* imag(VAs);

VAnonzero=VA(VA~=0);
PF=100*VAnonzero(:);
PF
VAreversenonzero=VAreverse(VAreverse~=0);
PFreverse=100*VAreversenonzero(:);
PFreverse

Qsum=0;
for i=1:totalbuses
    if busdata(i,8)==3
        for j=1:totalbuses
            Qsum=Qsum+ybus(i,j)*Vr(j);
        end
        Q(i)=-imag(conj(Vr(i))*Qsum);
        busdata(i,3)=100*Q(i)+busdata(i,5);
        QG(i) = busdata(i,3);
    end
end


Ps
Qs


Ploss= Ploss*100;
PG(1)=Ps + PL(1);
QG(1)=Qs + QL(1);
z(1:totalbuses,1)=busdata(:,1);
z(1:totalbuses,2)=busdata(:,8);
z(1:totalbuses,3)=abs(busdata(:,6));
z(1:totalbuses,4)=radtodeg(busdata(:,7));
z(1:totalbuses,5)=PG;
z(1:totalbuses,6)=QG;
z(1:totalbuses,7)=busdata(:,4);
z(1:totalbuses,8)=busdata(:,5);

zm = zeros(tnb,5);
zm(:,1)= linedata(:,1)  ;
zm(:,2)= linedata(:,2);
zm(:,4)= Ploss.';
zm(:,3)= PF;
zm(:,7)= PFreverse;
zm(:,6)= linedata(:,1)  ;
zm(:,5)= linedata(:,2);


app.UITable_3.Data=z;
app.UITable2.Data=zm;


        end

        % Value changed function: NoOfBussSpinner
        function NoOfBussSpinnerValueChanged(app, event)
            tnb = app.NoOfBussSpinner.Value;
            if (tnb == 3 )
                app.EditField_3.Enable = 'On';
                app.EditField_64.Enable = 'On';
                app.EditField_94.Enable = 'On';
                app.EditField_69.Enable = 'On';
                app.EditField_74.Enable = 'On';
                app.EditField_79.Enable = 'On';
                app.EditField_84.Enable = 'On';
                app.EditField_89.Enable = 'On';
                
                app.EditField_4.Enable = 'Off';
                app.EditField_65.Enable = 'Off';
                app.EditField_70.Enable = 'Off';
                app.EditField_75.Enable = 'Off';
                app.EditField_80.Enable = 'Off';
                app.EditField_85.Enable = 'Off';
                app.EditField_90.Enable = 'Off';
                app.EditField_95.Enable = 'Off';
                app.EditField_5.Enable = 'Off';
                app.EditField_66.Enable = 'Off';
                app.EditField_71.Enable = 'Off';
                app.EditField_76.Enable = 'Off';
                app.EditField_81.Enable = 'Off';
                app.EditField_86.Enable = 'Off';
                app.EditField_91.Enable = 'Off';
                app.EditField_96.Enable = 'Off';
            
            
        else if(tnb == 4)
                app.EditField_4.Enable = 'On';
                app.EditField_65.Enable = 'On';
                app.EditField_70.Enable = 'On';
                app.EditField_75.Enable = 'On';
                app.EditField_80.Enable = 'On';
                app.EditField_85.Enable = 'On';
                app.EditField_90.Enable = 'On';
                app.EditField_95.Enable = 'On';
                
                app.EditField_5.Enable = 'Off';
                app.EditField_66.Enable = 'Off';
                app.EditField_71.Enable = 'Off';
                app.EditField_76.Enable = 'Off';
                app.EditField_81.Enable = 'Off';
                app.EditField_86.Enable = 'Off';
                app.EditField_91.Enable = 'Off';
                app.EditField_96.Enable = 'Off';
            
                
        else if(tnb == 5)
                app.EditField_5.Enable = 'On';
                app.EditField_66.Enable = 'On';
                app.EditField_71.Enable = 'On';
                app.EditField_76.Enable = 'On';
                app.EditField_81.Enable = 'On';
                app.EditField_86.Enable = 'On';
                app.EditField_91.Enable = 'On';
                app.EditField_96.Enable = 'On'; 
                
        else
            app.EditField_3.Enable = 'Off';
                app.EditField_64.Enable = 'Off';
                app.EditField_94.Enable = 'Off';
                app.EditField_69.Enable = 'Off';
                app.EditField_74.Enable = 'Off';
                app.EditField_79.Enable = 'Off';
                app.EditField_84.Enable = 'Off';
                app.EditField_89.Enable = 'Off';
                app.EditField_4.Enable = 'Off';
                app.EditField_65.Enable = 'Off';
                app.EditField_70.Enable = 'Off';
                app.EditField_75.Enable = 'Off';
                app.EditField_80.Enable = 'Off';
                app.EditField_85.Enable = 'Off';
                app.EditField_90.Enable = 'Off';
                app.EditField_95.Enable = 'Off';
                app.EditField_5.Enable = 'Off';
                app.EditField_66.Enable = 'Off';
                app.EditField_71.Enable = 'Off';
                app.EditField_76.Enable = 'Off';
                app.EditField_81.Enable = 'Off';
                app.EditField_86.Enable = 'Off';
                app.EditField_91.Enable = 'Off';
                app.EditField_96.Enable = 'Off';
        
        end
        end
        
        end
        
             if (tnb == 3 )
                app.EditField_99.Enable = 'On';
                app.EditField_104.Enable = 'On';
                app.EditField_109.Enable = 'On';
                app.EditField_114.Enable = 'On';
                app.EditField_119.Enable = 'On';
                app.EditField_124.Enable = 'On';
                app.EditField_129.Enable = 'On';
                app.DropDown_3.Enable = 'On';
                
                app.EditField_100.Enable = 'Off';
                app.EditField_105.Enable = 'Off';
                app.EditField_110.Enable = 'Off';
                app.EditField_115.Enable = 'Off';
                app.EditField_120.Enable = 'Off';
                app.EditField_125.Enable = 'Off';
                app.EditField_130.Enable = 'Off';
                app.DropDown_4.Enable = 'Off';
                app.EditField_101.Enable = 'Off';
                app.EditField_106.Enable = 'Off';
                app.EditField_111.Enable = 'Off';
                app.EditField_116.Enable = 'Off';
                app.EditField_121.Enable = 'Off';
                app.EditField_126.Enable = 'Off';
                app.EditField_131.Enable = 'Off';
                app.DropDown_5.Enable = 'Off';


        else if (tnb == 4)
                app.EditField_100.Enable = 'On';
                app.EditField_105.Enable = 'On';
                app.EditField_110.Enable = 'On';
                app.EditField_115.Enable = 'On';
                app.EditField_120.Enable = 'On';
                app.EditField_125.Enable = 'On';
                app.EditField_130.Enable = 'On';
                app.DropDown_4.Enable = 'On';
                app.EditField_101.Enable = 'Off';
                app.EditField_106.Enable = 'Off';
                app.EditField_111.Enable = 'Off';
                app.EditField_116.Enable = 'Off';
                app.EditField_121.Enable = 'Off';
                app.EditField_126.Enable = 'Off';
                app.EditField_131.Enable = 'Off';
                app.DropDown_5.Enable = 'Off';
            
                
        else if (tnb == 5)
                app.EditField_101.Enable = 'On';
                app.EditField_106.Enable = 'On';
                app.EditField_111.Enable = 'On';
                app.EditField_116.Enable = 'On';
                app.EditField_121.Enable = 'On';
                app.EditField_126.Enable = 'On';
                app.EditField_131.Enable = 'On';
                app.DropDown_5.Enable = 'On';
        else
                app.EditField_99.Enable = 'Off';
                app.EditField_104.Enable = 'Off';
                app.EditField_109.Enable = 'Off';
                app.EditField_114.Enable = 'Off';
                app.EditField_119.Enable = 'Off';
                app.EditField_124.Enable = 'Off';
                app.EditField_129.Enable = 'Off';
                app.DropDown_3.Enable = 'Off';
                app.EditField_100.Enable = 'Off';
                app.EditField_105.Enable = 'Off';
                app.EditField_110.Enable = 'Off';
                app.EditField_115.Enable = 'Off';
                app.EditField_120.Enable = 'Off';
                app.EditField_125.Enable = 'Off';
                app.EditField_130.Enable = 'Off';
                app.DropDown_4.Enable = 'Off';
                app.EditField_101.Enable = 'Off';
                app.EditField_106.Enable = 'Off';
                app.EditField_111.Enable = 'Off';
                app.EditField_116.Enable = 'Off';
                app.EditField_121.Enable = 'Off';
                app.EditField_126.Enable = 'Off';
                app.EditField_131.Enable = 'Off';
                app.DropDown_5.Enable = 'Off';
        end
        end
             end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Start bus'; 'End bus'; 'R'; 'X'; 'G'; 'B'; 'T. Charging'; 'Y/2'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.PowerFlowCalculatorLabel = uilabel(app.UIFigure);
            app.PowerFlowCalculatorLabel.Text = 'Power Flow Calculator';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Bus No.'; 'PG MW'; 'QG MVAR'; 'PL MW'; 'QG MVAR'; '|V|'; 'Angle'; 'Bus Type'};
            app.UITable_2.RowName = {};
            app.CalculateForToleranceButton = uibutton(app.UIFigure, 'state');
            app.CalculateForToleranceButton.ValueChangedFcn = createCallbackFcn(app, @CalculateForToleranceButtonValueChanged, true);
            app.CalculateForToleranceButton.Text = 'Calculate For Tolerance ';
            app.UITable_3 = uitable(app.UIFigure);
            app.UITable_3.ColumnName = {'Bus No.'; 'Bus Type'; 'Voltage'; 'Angle'; 'MW   G'; 'MVAR   G'; 'MW    L'; 'MVAR    L'};
            app.UITable_3.RowName = {};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Enable = 'off';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Enable = 'off';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Enable = 'off';
            app.EditField_21Label = uilabel(app.UIFigure);
            app.EditField_21Label.Text = '';
            app.EditField_41Label = uilabel(app.UIFigure);
            app.EditField_41Label.Text = '';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Slack', 'PV', 'PQ'};
            app.DropDown.Value = 'Slack';
            app.EditField_62Label = uilabel(app.UIFigure);
            app.EditField_62Label.Text = '';
            app.EditField_62 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_63 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_64 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_64.Enable = 'off';
            app.EditField_65 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_65.Enable = 'off';
            app.EditField_66 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_66.Enable = 'off';
            app.EditField_67Label = uilabel(app.UIFigure);
            app.EditField_67Label.Text = '';
            app.EditField_67 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_68 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_69 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_69.Enable = 'off';
            app.EditField_70 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_70.Enable = 'off';
            app.EditField_71 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_71.Enable = 'off';
            app.EditField_72Label = uilabel(app.UIFigure);
            app.EditField_72Label.Text = '';
            app.EditField_72 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_73 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_74 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_74.Enable = 'off';
            app.EditField_75 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_75.Enable = 'off';
            app.EditField_76 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_76.Enable = 'off';
            app.EditField_77Label = uilabel(app.UIFigure);
            app.EditField_77Label.Text = '';
            app.EditField_77 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_78 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_79 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_79.Enable = 'off';
            app.EditField_80 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_80.Enable = 'off';
            app.EditField_81 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_81.Enable = 'off';
            app.EditField_82Label = uilabel(app.UIFigure);
            app.EditField_82Label.Text = '';
            app.EditField_82 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_83 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_84 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_84.Enable = 'off';
            app.EditField_85 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_85.Enable = 'off';
            app.EditField_86 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_86.Enable = 'off';
            app.EditField_87Label = uilabel(app.UIFigure);
            app.EditField_87Label.Text = '';
            app.EditField_87 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_88 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_89 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_89.Enable = 'off';
            app.EditField_90 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_90.Enable = 'off';
            app.EditField_91 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_91.Enable = 'off';
            app.EditField_92Label = uilabel(app.UIFigure);
            app.EditField_92Label.Text = '';
            app.EditField_92 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_93 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_94 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_94.Enable = 'off';
            app.EditField_95 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_95.Enable = 'off';
            app.EditField_96 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_96.Enable = 'off';
            app.EditField_97Label = uilabel(app.UIFigure);
            app.EditField_97Label.Text = '';
            app.EditField_97 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_98 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_99 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_99.Enable = 'off';
            app.EditField_100 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_100.Enable = 'off';
            app.EditField_101 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_101.Enable = 'off';
            app.EditField_102Label = uilabel(app.UIFigure);
            app.EditField_102Label.Text = '';
            app.EditField_102 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_103 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_104 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_104.Enable = 'off';
            app.EditField_105 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_105.Enable = 'off';
            app.EditField_106 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_106.Enable = 'off';
            app.EditField_107Label = uilabel(app.UIFigure);
            app.EditField_107Label.Text = '';
            app.EditField_107 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_108 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_109 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_109.Enable = 'off';
            app.EditField_110 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_110.Enable = 'off';
            app.EditField_111 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_111.Enable = 'off';
            app.EditField_112Label = uilabel(app.UIFigure);
            app.EditField_112Label.Text = '';
            app.EditField_112 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_113 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_114 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_114.Enable = 'off';
            app.EditField_115 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_115.Enable = 'off';
            app.EditField_116 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_116.Enable = 'off';
            app.EditField_117Label = uilabel(app.UIFigure);
            app.EditField_117Label.Text = '';
            app.EditField_117 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_118 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_119 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_119.Enable = 'off';
            app.EditField_120 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_120.Enable = 'off';
            app.EditField_121 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_121.Enable = 'off';
            app.EditField_122Label = uilabel(app.UIFigure);
            app.EditField_122Label.Text = '';
            app.EditField_122 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_123 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_124 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_124.Enable = 'off';
            app.EditField_125 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_125.Enable = 'off';
            app.EditField_126 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_126.Enable = 'off';
            app.EditField_127Label = uilabel(app.UIFigure);
            app.EditField_127Label.Text = '';
            app.EditField_127 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_128 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_129 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_129.Enable = 'off';
            app.EditField_130 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_130.Enable = 'off';
            app.EditField_131 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_131.Enable = 'off';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'Slack', 'PV', 'PQ'};
            app.DropDown_2.Value = 'PQ';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.Items = {'Slack', 'PV', 'PQ'};
            app.DropDown_3.Enable = 'off';
            app.DropDown_3.Value = 'PQ';
            app.DropDown_4 = uidropdown(app.UIFigure);
            app.DropDown_4.Items = {'Slack', 'PV', 'PQ'};
            app.DropDown_4.Enable = 'off';
            app.DropDown_4.Value = 'PV';
            app.DropDown_5 = uidropdown(app.UIFigure);
            app.DropDown_5.Items = {'Slack', 'PV', 'PQ'};
            app.DropDown_5.Enable = 'off';
            app.DropDown_5.Value = 'PV';
            app.NoOfIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NoOfIterationsEditFieldLabel.Text = 'No. Of Iterations ';
            app.NoOfIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoOfIterationsEditField.Editable = 'off';
            app.CorrectionFactorEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.NoOfBussSpinnerLabel = uilabel(app.UIFigure);
            app.NoOfBussSpinnerLabel.Text = 'No. Of Buss';
            app.NoOfBussSpinner = uispinner(app.UIFigure);
            app.NoOfBussSpinner.Limits = [2 5];
            app.NoOfBussSpinner.RoundFractionalValues = 'on';
            app.NoOfBussSpinner.ValueDisplayFormat = '%.0f';
            app.NoOfBussSpinner.ValueChangedFcn = createCallbackFcn(app, @NoOfBussSpinnerValueChanged, true);
            app.NoOfBussSpinner.Editable = 'off';
            app.NoOfBussSpinner.Value = 2;
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'From'; 'To'; 'Power Flow'; 'Power Losses'; 'From'; 'To'; 'Power Flow'};
            app.UITable2.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = power_flow(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end